classdef JMPTaskCreation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BodiesListBox              matlab.ui.control.ListBox
        BodiesListBoxLabel         matlab.ui.control.Label
        JointsListBox              matlab.ui.control.ListBox
        JointsListBoxLabel         matlab.ui.control.Label
        MarkersTextAreaLabel       matlab.ui.control.Label
        MarkersTextArea            matlab.ui.control.TextArea
        EndTimeField               matlab.ui.control.NumericEditField
        ToLabel                    matlab.ui.control.Label
        StartTimeField             matlab.ui.control.NumericEditField
        TimeRangesEditFieldLabel   matlab.ui.control.Label
        MarkersFileEditField       matlab.ui.control.EditField
        MarkersFileEditFieldLabel  matlab.ui.control.Label
        TaskNameEditField          matlab.ui.control.EditField
        TaskNameEditFieldLabel     matlab.ui.control.Label
        DeleteBodyButton           matlab.ui.control.Button
        EditBodyButton             matlab.ui.control.Button
        DeleteJointButton          matlab.ui.control.Button
        EditJointButton            matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        AddBodyButton              matlab.ui.control.Button
        AddJointButton             matlab.ui.control.Button
        EditMarkersButton          matlab.ui.control.Button
        OKButton                   matlab.ui.control.Button
        MarkerFileSearchButton     matlab.ui.control.Button
        EnabledCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        JMPBase;
        TaskApp JMPTaskCreation;
        Task = struct();
        JointSet cell;
        BodySet cell;
        JointNames string;
        BodyNames string;
        editingFlag logical = false;
        indexEditingCurrent;
    end
    
    methods (Access = public)


        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
            app.Task.marker_names = objects;
            app.MarkersTextArea.Value = strjoin(objects, " ");
        end

        function objects = getSelectedObjects(app)
            % Function to interface with ObjectSelectionWindow
            objects = app.Task.marker_names;
        end

        function jointSet = getJMPJointSet(app)
            jointSet = app.JointSet;
        end
        
        function addJoint(app, joint)
            app.JointSet{end+1} = joint;
            app.JointNames(end+1) = joint.Attributes.name;
            app.JointsListBox.Items = app.JointNames;
            app.EditJointButton.Enable = 'on';
            app.DeleteJointButton.Enable = 'on';
        end

        function editJoint(app, joint)
            selectedJointIndex = app.JointsListBox.ValueIndex;
            app.JointSet{selectedJointIndex} = joint;
            app.JointNames(selectedJointIndex) = joint.Attributes.name;
            app.JointsListBox.Items = app.JointNames;
        end

        function deleteJoint(app, jointIndex)
            app.JointSet = app.JointSet(~jointIndex);
            app.JointNames = app.JointNames(~jointIndex);
            app.JointsListBox.Items = app.JointNames;
            if length(app.JointSet) == 0
                app.EditJointButton.Enable = 'off';
                app.DeleteJointButton.Enable = 'off';
            end
        end

        function bodySet = getJMPBodySet(app)
            bodySet = app.BodySet;
        end
        
        function addBody(app, bodySet)
            app.BodySet{end+1} = bodySet;
            app.BodyNames(end+1) = bodySet.Attributes.name;
            app.BodiesListBox.Items = app.BodyNames;
            app.EditBodyButton.Enable = 'on';
            app.DeleteBodyButton.Enable = 'on';
        end

        function editBody(app, body)
            selectedBodyIndex = app.BodiesListBox.ValueIndex;
            app.BodySet{selectedBodyIndex} = body;
            app.BodyNames(selectedBodyIndex) = body.Attributes.name;
            app.BodiesListBox.Items = app.BodyNames;
        end

        function deleteBody(app, bodyIndex)
            app.BodySet = app.BodySet(~bodyIndex);
            app.BodyNames = app.BodyNames(~bodyIndex);
            app.BodiesListBox.Items = app.BodyNames;
            if length(app.BodySet) == 0
                app.EditBodyButton.Enable = 'off';
                app.DeleteBodyButton.Enable = 'off';
            end
        end

        function joints = getJMPModelJoints(app)
            joints = app.JMPBase.getJMPModelJoints();
        end

        function bodies = getJMPModelBodies(app)
            bodies = app.JMPBase.getJMPModelBodies();
        end
    end
    
    methods (Access = private)
        
        function makeEmptyTask(app)
            app.Task.Attributes.name = "Default";
            app.Task.index = 0;
            app.Task.is_enabled = 'true';
            app.Task.time_range = [0 0];
            app.Task.marker_file_name = "";
            app.Task.marker_names = [""];
            app.Task.JointSet = {};
            app.Task.BodySet = {};
        end

        function loadTask(app, task)
            app.Task = task;
            app.JointSet = task.JointSet;
            app.BodySet = task.BodySet;
            app.TaskNameEditField.Value = task.Attributes.name;
            if strcmp(task.is_enabled, 'true')
                app.EnabledCheckBox.Value = true;
            else
                app.EnabledCheckBox.Value = false;
            end
            app.StartTimeField.Value = task.time_range(1);
            app.EndTimeField.Value = task.time_range(2);
            app.MarkersFileEditField.Value = task.marker_file_name;
            app.MarkersTextArea.Value = strjoin(task.marker_names, " ");
            if isfield(task, "JointSet")
                for i = 1 : numel(task.JointSet)
                    app.JointNames(i) = task.JointSet{i}.Attributes.name;
                end
            end
            app.JointsListBox.Items = app.JointNames;
            if isfield(task, "BodySet")
                for i = 1 : numel(task.BodySet)
                    app.BodyNames(i) = task.BodySet{i}.Attributes.name;
                end
            end
            app.BodiesListBox.Items = app.BodyNames;
        end

        function parseMarkerFile(app)
            import org.opensim.modeling.TimeSeriesTableVec3
            if ~exist(app.Task.marker_file_name)
                return
            end
            timeSeriesTable = TimeSeriesTableVec3(app.Task.marker_file_name);
            numTimePoints = timeSeriesTable.getNumRows;
            timeVector = timeSeriesTable.getIndependentColumn;
    

            startTime = double(timeVector.get(0));
            endTime = double(timeVector.get(numTimePoints-1));
            app.Task.time_range = ...
                [startTime endTime];

            app.StartTimeField.Value = double(app.Task.time_range(1));
            app.EndTimeField.Value = double(app.Task.time_range(2));
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, JMPBase, JMPTask)
            app.JMPBase = JMPBase;
            app.EditMarkersButton.Enable = 'off';
            app.AddBodyButton.Enable = 'on';
            app.EditBodyButton.Enable = 'off';
            app.DeleteBodyButton.Enable = 'off';
            app.AddJointButton.Enable = 'on';
            app.EditJointButton.Enable = 'off';
            app.DeleteJointButton.Enable = 'off';
            if isempty(JMPTask)
                app.makeEmptyTask()
            else
                app.loadTask(JMPTask)
                app.editingFlag = true;
            end
            
        end

        % Value changed function: TaskNameEditField
        function TaskNameEditFieldValueChanged(app, event)
            taskName = app.TaskNameEditField.Value;
            app.Task.Attributes.name = convertStringsToChars(taskName);
        end

        % Value changed function: EnabledCheckBox
        function EnabledCheckBoxValueChanged(app, event)
            if app.EnabledCheckBox.Value
                app.Task.is_enabled = 'true';
            else
                app.Task.is_enabled = 'false';
            end
        end

        % Button pushed function: MarkerFileSearchButton
        function MarkerFileSearchButtonPushed(app, event)
        [file, path] = uigetfile('*.trc', "Select a trc marker file.");
        % User hit "Cancel"
        if isequal(file, 0)
            return
        end
  
        fullpath = fullfile(path, file);
    
        % Update the text field
        app.MarkersFileEditField.Value = getRelativePath(fullpath, pwd);
        app.Task.marker_file_name = fullpath;

        parseMarkerFile(app);
        app.EditMarkersButton.Enable = 'on';
        end

        % Value changed function: MarkersFileEditField
        function MarkersFileEditFieldValueChanged(app, event)
            app.Task.marker_file_name = app.MarkersFileEditField.Value;
            parseMarkerFile(app);
        end

        % Button pushed function: EditMarkersButton
        function EditMarkersButtonPushed(app, event)
            modelMarkers = app.JMPBase.getJMPModelMarkers();
            ObjectSelectionWindow(app, modelMarkers, ...
                app.Task.marker_names);
        end

        % Button pushed function: AddJointButton
        function AddJointButtonPushed(app, event)
            JMPJointSelection(app, []);
        end

        % Button pushed function: EditJointButton
        function EditJointButtonPushed(app, event)
            % if 
            selectedJointIndex = app.JointsListBox.ValueIndex;
            JMPJointSelection(app, app.JointSet{selectedJointIndex});
        end

        % Button pushed function: DeleteJointButton
        function DeleteJointButtonPushed(app, event)
            selectedJointIndex = app.JointsListBox.ValueIndex;
            indicesVector = 1:1:numel(app.JointsListBox.Items) == selectedJointIndex;
            app.deleteJoint(indicesVector);
        end

        % Button pushed function: AddBodyButton
        function AddBodyButtonPushed(app, event)
            JMPBodySelection(app, [])
        end

        % Button pushed function: EditBodyButton
        function EditBodyButtonPushed(app, event)
            selectedBodyIndex = app.BodiesListBox.ValueIndex;
            JMPBodySelection(app, app.BodySet{selectedBodyIndex});
        end

        % Button pushed function: DeleteBodyButton
        function DeleteBodyButtonPushed(app, event)
            selectedBodyIndex = app.BodiesListBox.ValueIndex;
            indicesVector = 1:1:numel(app.BodiesListBox.Items) == selectedBodyIndex;
            app.deleteBody(indicesVector);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
            if ~isempty(app.JointSet)
                app.Task.JointSet = app.JointSet;
            end
            if ~isempty(app.BodySet)
                app.Task.BodySet = app.BodySet;
            end
            
            if ~app.editingFlag
                app.Task.index = numel(app.JMPBase.getTaskList)+1;
                app.JMPBase.addTask(app.Task);
            else
                app.JMPBase.editTask(app.Task)
            end

            delete(app.UIFigure);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Value changed function: StartTimeField
        function StartTimeFieldValueChanged(app, event)
            value = app.StartTimeField.Value;
            app.Task.time_range(1) = value;
        end

        % Value changed function: EndTimeField
        function EndTimeFieldValueChanged(app, event)
            value = app.EndTimeField.Value;
            app.Task.time_range(2) = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnabledCheckBox = uicheckbox(app.UIFigure);
            app.EnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnabledCheckBoxValueChanged, true);
            app.EnabledCheckBox.Text = 'Enabled';
            app.EnabledCheckBox.Value = true;
            app.MarkerFileSearchButton = uibutton(app.UIFigure, 'push');
            app.MarkerFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerFileSearchButtonPushed, true);
            app.MarkerFileSearchButton.Icon = fullfile(pathToMLAPP, 'Images', 'folderIcon.svg');
            app.MarkerFileSearchButton.Text = '';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.EditMarkersButton = uibutton(app.UIFigure, 'push');
            app.EditMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @EditMarkersButtonPushed, true);
            app.EditMarkersButton.Text = 'Edit Markers';
            app.AddJointButton = uibutton(app.UIFigure, 'push');
            app.AddJointButton.ButtonPushedFcn = createCallbackFcn(app, @AddJointButtonPushed, true);
            app.AddJointButton.Text = 'Add Joint';
            app.AddBodyButton = uibutton(app.UIFigure, 'push');
            app.AddBodyButton.ButtonPushedFcn = createCallbackFcn(app, @AddBodyButtonPushed, true);
            app.AddBodyButton.Text = 'Add Body';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.EditJointButton = uibutton(app.UIFigure, 'push');
            app.EditJointButton.ButtonPushedFcn = createCallbackFcn(app, @EditJointButtonPushed, true);
            app.EditJointButton.Text = 'Edit Joint';
            app.DeleteJointButton = uibutton(app.UIFigure, 'push');
            app.DeleteJointButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteJointButtonPushed, true);
            app.DeleteJointButton.Text = 'Delete Joint';
            app.EditBodyButton = uibutton(app.UIFigure, 'push');
            app.EditBodyButton.ButtonPushedFcn = createCallbackFcn(app, @EditBodyButtonPushed, true);
            app.EditBodyButton.Text = 'Edit Body';
            app.DeleteBodyButton = uibutton(app.UIFigure, 'push');
            app.DeleteBodyButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBodyButtonPushed, true);
            app.DeleteBodyButton.Text = 'Delete Body';
            app.TaskNameEditFieldLabel = uilabel(app.UIFigure);
            app.TaskNameEditFieldLabel.Text = 'Task Name';
            app.TaskNameEditField = uieditfield(app.UIFigure, 'text');
            app.TaskNameEditField.ValueChangedFcn = createCallbackFcn(app, @TaskNameEditFieldValueChanged, true);
            app.TaskNameEditField.Value = 'Default';
            app.MarkersFileEditFieldLabel = uilabel(app.UIFigure);
            app.MarkersFileEditFieldLabel.Text = 'Markers File';
            app.MarkersFileEditField = uieditfield(app.UIFigure, 'text');
            app.MarkersFileEditField.ValueChangedFcn = createCallbackFcn(app, @MarkersFileEditFieldValueChanged, true);
            app.TimeRangesEditFieldLabel = uilabel(app.UIFigure);
            app.TimeRangesEditFieldLabel.Text = 'Time Range (s):';
            app.StartTimeField = uieditfield(app.UIFigure, 'numeric');
            app.StartTimeField.ValueChangedFcn = createCallbackFcn(app, @StartTimeFieldValueChanged, true);
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To:';
            app.EndTimeField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimeField.ValueChangedFcn = createCallbackFcn(app, @EndTimeFieldValueChanged, true);
            app.MarkersTextArea = uitextarea(app.UIFigure);
            app.MarkersTextArea.Editable = 'off';
            app.MarkersTextAreaLabel = uilabel(app.UIFigure);
            app.MarkersTextAreaLabel.Text = 'Markers';
            app.JointsListBoxLabel = uilabel(app.UIFigure);
            app.JointsListBoxLabel.Text = 'Joints';
            app.JointsListBox = uilistbox(app.UIFigure);
            app.JointsListBox.Items = {''};
            app.JointsListBox.Value = {};
            app.BodiesListBoxLabel = uilabel(app.UIFigure);
            app.BodiesListBoxLabel.Text = 'Bodies';
            app.BodiesListBox = uilistbox(app.UIFigure);
            app.BodiesListBox.Items = {};
            app.BodiesListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPTaskCreation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end