classdef MTPBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        LoadSettingsFileButton          matlab.ui.control.Button
        RcnlLogo                        matlab.ui.control.Image
        MTPInputsButton                 matlab.ui.control.Button
        SynergyExtrapolationButton      matlab.ui.control.Button
        MuscletendonLengthInitalizationButton  matlab.ui.control.Button
        MuscleGroupsButton              matlab.ui.control.Button
        MTPImage                        matlab.ui.control.Image
        Mask1                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        CoordinateListEditButton        matlab.ui.control.Button
        CoordinateListWarning           matlab.ui.control.Image
        CoordinatesListTextAreaLabel    matlab.ui.control.Label
        CoordinatesListTextArea         matlab.ui.control.TextArea
        InputDataDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsDirectoryError           matlab.ui.control.Image
        ResultsDirectoryEditField       matlab.ui.control.EditField
        ResultsDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsDirectorySearchButton    matlab.ui.control.Button
        InputDataError                  matlab.ui.control.Image
        InputDataEditField              matlab.ui.control.EditField
        InputDataSearchButton           matlab.ui.control.Button
        InputOsimxFileWarning           matlab.ui.control.Image
        InputOsimxFileEditField         matlab.ui.control.EditField
        InputOsimxFileEditFieldLabel    matlab.ui.control.Label
        InputOsimxFileSearchButton      matlab.ui.control.Button
        InputModelFileError             matlab.ui.control.Image
        InputModelFileEditField         matlab.ui.control.EditField
        InputModelFileEditFieldLabel    matlab.ui.control.Label
        InputModelFileSearchButton      matlab.ui.control.Button
        MuscleGroupsTab                 matlab.ui.container.Tab
        EditCollectedEmgGroupsButton    matlab.ui.control.Button
        EditMissingEmgGroupsButton      matlab.ui.control.Button
        EditFiberLengthGroupsButton     matlab.ui.control.Button
        EditActivationGroupsButton      matlab.ui.control.Button
        ActivationMuscleGroupsTextArea  matlab.ui.control.TextArea
        ActivationMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        CollectedEMGMuscleGroupsTextArea  matlab.ui.control.TextArea
        CollectedEMGMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        MissingEMGMuscleGroupsTextArea  matlab.ui.control.TextArea
        MissingEMGMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        NormalizedFiberLengthMuscleGroupsTextArea  matlab.ui.control.TextArea
        NormalizedFiberLengthMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        MuscleTendonLengthInitializationTab  matlab.ui.container.Tab
        EnableMuscletendonLengthInitializationCheckBox  matlab.ui.control.CheckBox
        PassiveDataDirError             matlab.ui.control.Image
        PassiveDataInputDirectoryEditField  matlab.ui.control.EditField
        PassiveDataInputDirectoryEditFieldLabel  matlab.ui.control.Label
        PasssiveDataSearchButton        matlab.ui.control.Button
        SynergyExtrapolationTab         matlab.ui.container.Tab
        NumSynergiesSpinner             matlab.ui.control.Spinner
        NumSynergiesSpinnerLabel        matlab.ui.control.Label
        EnableSynergyExtrapolationCheckBox  matlab.ui.control.CheckBox
        MuscletendonModelPersonalizationToolLabel  matlab.ui.control.Label
    end

    
    properties (Access = private, SetObservable)
        model_coordinates string = [];
        model_groups string = [];
        
        input_model_file string = "";
        input_osimx_file string = "";
        data_directory string = "";
        results_directory string = "";
        coordinate_list string = [];

        activation_muscle_groups string = [];
        normalized_fiber_length_muscle_groups string = [];
        missing_emg_channel_muscle_groups string = [];
        collected_emg_channel_muscle_groups string = [];
        
        MuscleTendonLengthInitialization struct = struct([])
        passive_data_input_directory string = "";
        MTLIEnabled string = "false";


        MTPSynergyExtrapolation struct = struct([])
        SynxEnabled string = "false";
        number_of_synergies = 0;

        MTPTaskList struct = struct([])

        objectSelectionType string = "";  % Used to filter in setSelectedObjects
        ErrorFlag logical = false;
        currentSettingsFile string; % Variable to store the current settings file name.
        needToSave logical = false; % flag indicating if the settings file changed and needs to be saved before running.
        modelErrorFlag logical = false;
        osimxWarningFlag logical = false;
        dataDirectoryErrorFlag logical = false;

        timePoints double = [];
        idLabels string = [];
        emgLabels string = [];

    end % Description
    
    properties (Access = public)
        accuracy double = 1e-6;
        diff_min_change double = 0.001;
        optimality_tolerance double = 1e-5;
        function_tolerance double = 1e-5;
        step_tolerance double = 1e-5;
        max_function_evaluations double = 200;
    end
    
    methods (Access = private)
        
        function ErrorsCallback(app)
            app.ErrorFlag = false;

            % Check for osim model errors
            [app.modelErrorFlag, modelErrorMessage] = parseModelFileGui( ...
                app, app.input_model_file);
            if app.modelErrorFlag
                throwGuiError(modelErrorMessage, ...
                    app.InputModelFileEditField, app.InputModelFileError)
                app.ErrorFlag = true;
            else
                clearGuiError(app.InputModelFileEditField, app.InputModelFileError)
            end
            
            [app.osimxWarningFlag, osimxErrorMessage] = parseOsimxFileGui( ...
                app, app.input_osimx_file);
            if app.osimxWarningFlag
                throwGuiWarning(osimxErrorMessage, ...
                    app.InputOsimxFileEditField, app.InputOsimxFileWarning);
            else
                clearGuiError(app.InputOsimxFileEditField, app.InputOsimxFileWarning);
            end

            [app.dataDirectoryErrorFlag, dataDirectoryErrorMessage] = ...
                parseMtpDataDirectoryGui(app, app.data_directory)

            app.EnableActionsCallback();
        end

        function EnableActionsCallback(app)
            if strcmp(app.input_model_file, "") || app.modelErrorFlag
                app.CoordinateListEditButton.Enable = 'off';
                app.MuscleGroupsButton.Enable = 'off';
                app.CoordinateListEditButton.Tooltip = "You must select a valid model file before selecting coordinates";
                app.MuscletendonLengthInitalizationButton.Enable = 'off';
                app.SynergyExtrapolationButton.Enable = 'off';
            else
                app.CoordinateListEditButton.Enable = 'on';
                app.MuscleGroupsButton.Enable = 'on';
                app.MuscletendonLengthInitalizationButton.Enable = 'on';
                app.SynergyExtrapolationButton.Enable = 'on';
            end
        end

        function settingsTree = setOptimizationParams(app, settingsTree)
            settingsTree.accuracy = app.accuracy;
            settingsTree.diff_min_change = app.diff_min_change;
            settingsTree.optimality_tolerance = app.optimality_tolerance;
            settingsTree.function_tolerance = app.function_tolerance;
            settingsTree.step_tolerance = app.step_tolerance;
            settingsTree.max_function_evaluations = app.max_function_evaluations;
        end
    end
    
    methods (Access = public)

        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
        switch app.objectSelectionType
            case 'coordinate'
                app.coordinate_list = objects;
            case 'activationGroup'
                app.activation_muscle_groups = objects;
            case 'fiberLengthGroup'
                app.normalized_fiber_length_muscle_groups = objects;
            case 'missingEmgGroup'
                app.missing_emg_channel_muscle_groups = objects;
            case 'collectedEmgGroup'
                app.collected_emg_channel_muscle_groups = objects;
        end
            app.EnableActionsCallback()
        end
        
        function modelFile = getInputModelFile(app)
            modelFile = app.input_model_file;
        end

        function model_coordinates = getModelCoordinate(app)
            model_coordinates = app.model_coordinates;
        end

        function SetModelCoordinates(app, coordinates)
            app.model_coordinates = coordinates;
        end

        function SetModelGroups(app, groups)
            app.model_groups = groups;
        end
        
        function setTimePoints(app, timePoints)
            app.timePoints = timePoints;
        end

        function setIDLabels(app, idLabels)
            app.idLabels = idLabels;
        end
        
        function setEmgLabels(app, emgLabels)
            app.emgLabels = emgLabels;
        end
    end

    properties(Access = private)  % listner properties
        InputModelFileListener
        OsimXFileListener
        DataDirectoryListener
        ResultsDirectoryListener
        CoordinateListListener
        ActivationGroupsListener
        FiberLengthGroupsListener
        MissingEmgGroupsListener
        CollectedEmgGroupsListener
        PassiveDataDirectoryListener
    end

    methods (Access = private) % listener methods
        function makeListeners(app)
            app.InputModelFileListener = addlistener(app, ...
                'input_model_file', 'PostSet', ...
                @(src,event)InputModelFileListenerFunction(app));

            app.OsimXFileListener = addlistener(app, ...
                'input_osimx_file', 'PostSet', ...
                @(src,event)OsimXFileListenerFunction(app));

            app.DataDirectoryListener = addlistener(app, ...
                'data_directory', 'PostSet', ...
                @(src,event)DataDirectoryListenerFunction(app));

            app.ResultsDirectoryListener = addlistener(app, ...
                'results_directory', 'PostSet', ...
                @(src,event)ResultsDirectoryListenerFunction(app));

            app.CoordinateListListener = addlistener(app, ...
                'coordinate_list', 'PostSet', ...
                @(src,event)CoordinateListListenerFunction(app));

            app.ActivationGroupsListener = addlistener(app, ...
                'activation_muscle_groups', 'PostSet', ...
                @(src,event)ActivationGroupsListenerFunction(app));

            app.FiberLengthGroupsListener = addlistener(app, ...
                'normalized_fiber_length_muscle_groups', 'PostSet', ...
                @(src,event)FiberLengthGroupsListenerFunction(app));

            app.MissingEmgGroupsListener = addlistener(app, ...
                'missing_emg_channel_muscle_groups', 'PostSet', ...
                @(src,event)MissingEmgGroupsListenerFunction(app));

            app.CollectedEmgGroupsListener = addlistener(app, ...
                'collected_emg_channel_muscle_groups', 'PostSet', ...
                @(src,event)CollectedEmgGroupsListenerFunction(app));

            app.PassiveDataDirectoryListener = addlistener(app, ...
                'passive_data_input_directory', 'PostSet', ...
                @(src,event)PassiveDataDirectoryListenerFunction(app));
        end

        function InputModelFileListenerFunction(app)
            app.InputModelFileEditField.Value = getRelativePath( ...
                app.input_model_file);
            parseModelFileGui(app, app.input_model_file);
            app.ErrorsCallback()
            app.needToSave = true;
        end

        function OsimXFileListenerFunction(app)
            app.InputOsimxFileEditField.Value = getRelativePath( ...
                app.input_osimx_file);
            app.ErrorsCallback()
            app.needToSave = true;
        end

        function DataDirectoryListenerFunction(app)
            app.InputDataEditField.Value = getRelativePath( ...
                app.data_directory);
            app.ErrorsCallback()
            app.needToSave = true;
        end

        function ResultsDirectoryListenerFunction(app)
            app.ResultsDirectoryEditField.Value = getRelativePath( ...
                app.results_directory);
            app.needToSave = true;
        end

        function CoordinateListListenerFunction(app)
            app.CoordinatesListTextArea.Value =  ...
                strjoin(app.coordinate_list, ", ");
            app.needToSave = true;
        end

        function ActivationGroupsListenerFunction(app)
            app.ActivationMuscleGroupsTextArea.Value = ...
                strjoin(app.activation_muscle_groups, ", ");
            app.needToSave;
        end

        function FiberLengthGroupsListenerFunction(app)
            app.NormalizedFiberLengthMuscleGroupsTextArea.Value = ...
                strjoin(app.normalized_fiber_length_muscle_groups, ", ");
            app.needToSave;
        end

        function MissingEmgGroupsListenerFunction(app)
            app.MissingEMGMuscleGroupsTextArea.Value = ...
                strjoin(app.missing_emg_channel_muscle_groups, ", ");
            app.needToSave;
        end

        function CollectedEmgGroupsListenerFunction(app)
            app.CollectedEMGMuscleGroupsTextArea.Value = ...
                strjoin(app.collected_emg_channel_muscle_groups, ", ");
            app.needToSave;
        end

        function PassiveDataDirectoryListenerFunction(app)
            app.PassiveDataInputDirectoryEditField.Value = ...
                getRelativePath(app.passive_data_input_directory);
            app.ErrorsCallback()
            app.needToSave;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.makeListeners()
        end

        % Button pushed function: MTPInputsButton
        function MTPInputsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputsTab;
        end

        % Button pushed function: SynergyExtrapolationButton
        function SynergyExtrapolationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SynergyExtrapolationTab;
        end

        % Button pushed function: MuscletendonLengthInitalizationButton
        function MuscletendonLengthInitalizationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MuscleTendonLengthInitializationTab;
        end

        % Button pushed function: MuscleGroupsButton
        function MuscleGroupsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MuscleGroupsTab;
            
        end

        % Button pushed function: InputModelFileSearchButton
        function InputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_model_file = fullfile(path, file);
        end

        % Button pushed function: InputOsimxFileSearchButton
        function InputOsimxFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osimx', "Select an Osimx model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_osimx_file = fullfile(path, file);
        end

        % Button pushed function: ResultsDirectorySearchButton
        function ResultsDirectorySearchButtonPushed(app, event)
            folder = uigetdir("Select a folder to store your results");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.results_directory = folder;
        end

        % Button pushed function: InputDataSearchButton
        function InputDataSearchButtonPushed(app, event)
            folder = uigetdir("Select your input data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.data_directory = folder;
        end

        % Button pushed function: CoordinateListEditButton
        function CoordinateListEditButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_coordinates, ...
                app.coordinate_list)
            app.objectSelectionType = 'coordinate';
        end

        % Value changed function: InputModelFileEditField
        function InputModelFileEditFieldValueChanged(app, event)
            app.input_model_file = getAbsolutePath( ...
                app.InputModelFileEditField.Value);
        end

        % Value changed function: InputOsimxFileEditField
        function InputOsimxFileEditFieldValueChanged(app, event)
            app.input_osimx_file = getAbsolutePath( ...
                app.InputOsimxFileEditField.Value);
        end

        % Value changed function: InputDataEditField
        function InputDataEditFieldValueChanged(app, event)
            app.data_directory = getAbsolutePath( ...
                app.InputDataEditField.Value);
        end

        % Value changed function: ResultsDirectoryEditField
        function ResultsDirectoryEditFieldValueChanged(app, event)
            app.results_directory = getAbsolutePath( ...
                app.ResultsDirectoryEditField.Value);
        end

        % Button pushed function: EditActivationGroupsButton
        function EditActivationGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.activation_muscle_groups)
            app.objectSelectionType = 'activationGroup';
        end

        % Button pushed function: EditFiberLengthGroupsButton
        function EditFiberLengthGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.normalized_fiber_length_muscle_groups)
            app.objectSelectionType = 'fiberLengthGroup';
        end

        % Button pushed function: EditMissingEmgGroupsButton
        function EditMissingEmgGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.missing_emg_channel_muscle_groups)
            app.objectSelectionType = 'missingEmgGroup';
        end

        % Button pushed function: EditCollectedEmgGroupsButton
        function EditCollectedEmgGroupsButtonPushed(app, event)
            %% should read directly from EMG file
            
            ObjectSelectionWindow(app, app.model_groups, ...
                app.collected_emg_channel_muscle_groups)
            app.objectSelectionType = 'collectedEmgGroup';
        end

        % Button pushed function: PasssiveDataSearchButton
        function PasssiveDataSearchButtonPushed(app, event)
            folder = uigetdir("Select your passive data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.passive_data_input_directory = folder;
        end

        % Value changed function: PassiveDataInputDirectoryEditField
        function PassiveDataInputDirectoryEditFieldValueChanged(app, event)
            app.passive_data_input_directory = getAbsolutePath( ...
                app.PassiveDataInputDirectoryEditField.Value);
        end

        % Value changed function: 
        % EnableMuscletendonLengthInitializationCheckBox
        function EnableMuscletendonLengthInitializationCheckBoxValueChanged(app, event)
            if app.EnableMuscletendonLengthInitializationCheckBox.Value
                app.MTLIEnabled = "true";
            else
                app.MTLIEnabled = "false";
            end
            
        end

        % Value changed function: EnableSynergyExtrapolationCheckBox
        function EnableSynergyExtrapolationCheckBoxValueChanged(app, event)
            if app.EnableSynergyExtrapolationCheckBox.Value
                app.SynxEnabled = "true";
            else
                app.SynxEnabled = "false";
            end
            
        end

        % Value changed function: NumSynergiesSpinner
        function NumSynergiesSpinnerValueChanged(app, event)
            app.number_of_synergies = app.NumSynergiesSpinner.Value;
        end

        % Button pushed function: LoadSettingsFileButton
        function LoadSettingsFileButtonPushed(app, event)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web("https://nmsm.rice.edu/guides-and-publications/tool-overviews/model-personalization/muscle-tendon-personalization/")
        end

        % Image clicked function: RcnlLogo
        function RcnlLogoImageClicked(app, event)
            web("https://nmsm.rice.edu/")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MuscletendonModelPersonalizationToolLabel = uilabel(app.UIFigure);
            app.MuscletendonModelPersonalizationToolLabel.Text = 'Muscle-tendon Model Personalization Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputModelFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputModelFileSearchButtonPushed, true);
            app.InputModelFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputModelFileEditFieldLabel.Text = 'Input Model File';
            app.InputModelFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputModelFileEditFieldValueChanged, true);
            app.InputModelFileError = uiimage(app.InputsTab);
            app.InputModelFileError.Visible = 'off';
            app.InputModelFileError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputOsimxFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputOsimxFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputOsimxFileSearchButtonPushed, true);
            app.InputOsimxFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputOsimxFileSearchButton.Text = '';
            app.InputOsimxFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputOsimxFileEditFieldLabel.Text = 'Input Osimx File';
            app.InputOsimxFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputOsimxFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputOsimxFileEditFieldValueChanged, true);
            app.InputOsimxFileWarning = uiimage(app.InputsTab);
            app.InputOsimxFileWarning.Visible = 'off';
            app.InputOsimxFileWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.InputDataSearchButton = uibutton(app.InputsTab, 'push');
            app.InputDataSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputDataSearchButtonPushed, true);
            app.InputDataSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputDataSearchButton.Text = '';
            app.InputDataEditField = uieditfield(app.InputsTab, 'text');
            app.InputDataEditField.ValueChangedFcn = createCallbackFcn(app, @InputDataEditFieldValueChanged, true);
            app.InputDataError = uiimage(app.InputsTab);
            app.InputDataError.Visible = 'off';
            app.InputDataError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.ResultsDirectorySearchButton = uibutton(app.InputsTab, 'push');
            app.ResultsDirectorySearchButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsDirectorySearchButtonPushed, true);
            app.ResultsDirectorySearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.ResultsDirectorySearchButton.Text = '';
            app.ResultsDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.ResultsDirectoryEditFieldLabel.Text = 'Results Directory';
            app.ResultsDirectoryEditField = uieditfield(app.InputsTab, 'text');
            app.ResultsDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @ResultsDirectoryEditFieldValueChanged, true);
            app.ResultsDirectoryError = uiimage(app.InputsTab);
            app.ResultsDirectoryError.Visible = 'off';
            app.ResultsDirectoryError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputDataDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.InputDataDirectoryEditFieldLabel.Text = 'Input Data Directory';
            app.CoordinatesListTextArea = uitextarea(app.InputsTab);
            app.CoordinatesListTextArea.Editable = 'off';
            app.CoordinatesListTextAreaLabel = uilabel(app.InputsTab);
            app.CoordinatesListTextAreaLabel.Text = 'Coordinates List';
            app.CoordinateListWarning = uiimage(app.InputsTab);
            app.CoordinateListWarning.Visible = 'off';
            app.CoordinateListWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.CoordinateListEditButton = uibutton(app.InputsTab, 'push');
            app.CoordinateListEditButton.ButtonPushedFcn = createCallbackFcn(app, @CoordinateListEditButtonPushed, true);
            app.CoordinateListEditButton.Enable = 'off';
            app.CoordinateListEditButton.Text = 'Edit';
            app.MuscleGroupsTab = uitab(app.TabGroup);
            app.NormalizedFiberLengthMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.NormalizedFiberLengthMuscleGroupsTextAreaLabel.Text = 'Normalized Fiber Length Muscle Groups';
            app.NormalizedFiberLengthMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.NormalizedFiberLengthMuscleGroupsTextArea.Editable = 'off';
            app.MissingEMGMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.MissingEMGMuscleGroupsTextAreaLabel.Text = 'Missing EMG Muscle Groups';
            app.MissingEMGMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.MissingEMGMuscleGroupsTextArea.Editable = 'off';
            app.CollectedEMGMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.CollectedEMGMuscleGroupsTextAreaLabel.Text = 'Collected EMG Muscle Groups';
            app.CollectedEMGMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.CollectedEMGMuscleGroupsTextArea.Editable = 'off';
            app.ActivationMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.ActivationMuscleGroupsTextAreaLabel.Text = 'Activation Muscle Groups';
            app.ActivationMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.ActivationMuscleGroupsTextArea.Editable = 'off';
            app.EditActivationGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditActivationGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditActivationGroupsButtonPushed, true);
            app.EditActivationGroupsButton.Text = 'Edit';
            app.EditFiberLengthGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditFiberLengthGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditFiberLengthGroupsButtonPushed, true);
            app.EditFiberLengthGroupsButton.Text = 'Edit';
            app.EditMissingEmgGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditMissingEmgGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditMissingEmgGroupsButtonPushed, true);
            app.EditMissingEmgGroupsButton.Text = 'Edit';
            app.EditCollectedEmgGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditCollectedEmgGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditCollectedEmgGroupsButtonPushed, true);
            app.EditCollectedEmgGroupsButton.Text = 'Edit';
            app.MuscleTendonLengthInitializationTab = uitab(app.TabGroup);
            app.PasssiveDataSearchButton = uibutton(app.MuscleTendonLengthInitializationTab, 'push');
            app.PasssiveDataSearchButton.ButtonPushedFcn = createCallbackFcn(app, @PasssiveDataSearchButtonPushed, true);
            app.PasssiveDataSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.PasssiveDataSearchButton.Text = '';
            app.PassiveDataInputDirectoryEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.PassiveDataInputDirectoryEditFieldLabel.Text = 'Passive Data Input Directory';
            app.PassiveDataInputDirectoryEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'text');
            app.PassiveDataInputDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @PassiveDataInputDirectoryEditFieldValueChanged, true);
            app.PassiveDataDirError = uiimage(app.MuscleTendonLengthInitializationTab);
            app.PassiveDataDirError.Visible = 'off';
            app.PassiveDataDirError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.EnableMuscletendonLengthInitializationCheckBox = uicheckbox(app.MuscleTendonLengthInitializationTab);
            app.EnableMuscletendonLengthInitializationCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableMuscletendonLengthInitializationCheckBoxValueChanged, true);
            app.EnableMuscletendonLengthInitializationCheckBox.Text = 'Enable Muscle-tendon Length Initialization';
            app.SynergyExtrapolationTab = uitab(app.TabGroup);
            app.EnableSynergyExtrapolationCheckBox = uicheckbox(app.SynergyExtrapolationTab);
            app.EnableSynergyExtrapolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSynergyExtrapolationCheckBoxValueChanged, true);
            app.EnableSynergyExtrapolationCheckBox.Text = 'Enable Synergy Extrapolation';
            app.NumSynergiesSpinnerLabel = uilabel(app.SynergyExtrapolationTab);
            app.NumSynergiesSpinnerLabel.Text = 'Num Synergies';
            app.NumSynergiesSpinner = uispinner(app.SynergyExtrapolationTab);
            app.NumSynergiesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumSynergiesSpinnerValueChanged, true);
            app.Mask1 = uiimage(app.UIFigure);
            app.Mask1.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'greyMask.png');
            app.MTPImage = uiimage(app.UIFigure);
            app.MTPImage.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'mtpFigure.png');
            app.MuscleGroupsButton = uibutton(app.UIFigure, 'push');
            app.MuscleGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @MuscleGroupsButtonPushed, true);
            app.MuscleGroupsButton.Enable = 'off';
            app.MuscleGroupsButton.Text = 'Muscle Groups';
            app.MuscletendonLengthInitalizationButton = uibutton(app.UIFigure, 'push');
            app.MuscletendonLengthInitalizationButton.ButtonPushedFcn = createCallbackFcn(app, @MuscletendonLengthInitalizationButtonPushed, true);
            app.MuscletendonLengthInitalizationButton.Enable = 'off';
            app.MuscletendonLengthInitalizationButton.Text = 'Muscle-tendon Length Initalization';
            app.SynergyExtrapolationButton = uibutton(app.UIFigure, 'push');
            app.SynergyExtrapolationButton.ButtonPushedFcn = createCallbackFcn(app, @SynergyExtrapolationButtonPushed, true);
            app.SynergyExtrapolationButton.Enable = 'off';
            app.SynergyExtrapolationButton.Text = 'Synergy Extrapolation';
            app.MTPInputsButton = uibutton(app.UIFigure, 'push');
            app.MTPInputsButton.ButtonPushedFcn = createCallbackFcn(app, @MTPInputsButtonPushed, true);
            app.MTPInputsButton.Text = 'MTP Inputs';
            app.RcnlLogo = uiimage(app.UIFigure);
            app.RcnlLogo.ImageClickedFcn = createCallbackFcn(app, @RcnlLogoImageClicked, true);
            app.RcnlLogo.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'rcnlIcon.png');
            app.LoadSettingsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsFileButtonPushed, true);
            app.LoadSettingsFileButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTPBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end