classdef NCPBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NCPTaskButton                   matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        LoadSettingsFileButton          matlab.ui.control.Button
        RcnlLogo                        matlab.ui.control.Image
        InputsButton                    matlab.ui.control.Button
        AdvancedButton                  matlab.ui.control.Button
        AuxiliaryToolsButton            matlab.ui.control.Button
        MuscleGroupsButton              matlab.ui.control.Button
        MTPImage                        matlab.ui.control.Image
        Mask1                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        MTPResultsDirectoryLabel        matlab.ui.control.Label
        MTPResultsDirEditField          matlab.ui.control.EditField
        MTPResultsDirSearchButton       matlab.ui.control.Button
        MTPResultsDirError              matlab.ui.control.Image
        TaskPrefixesError               matlab.ui.control.Image
        TaskPrefixesWarning             matlab.ui.control.Image
        TaskPrefixesEditField           matlab.ui.control.EditField
        TaskPrefixesEditFieldLabel      matlab.ui.control.Label
        CoordinateListEditButton        matlab.ui.control.Button
        CoordinateListWarning           matlab.ui.control.Image
        CoordinatesListTextAreaLabel    matlab.ui.control.Label
        CoordinatesListTextArea         matlab.ui.control.TextArea
        InputDataDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsDirectoryError           matlab.ui.control.Image
        NCPResultsDirectoryEditField    matlab.ui.control.EditField
        NCPResultsDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsDirectorySearchButton    matlab.ui.control.Button
        InputDataError                  matlab.ui.control.Image
        InputDataEditField              matlab.ui.control.EditField
        InputDataSearchButton           matlab.ui.control.Button
        InputOsimxFileWarning           matlab.ui.control.Image
        InputOsimxFileEditField         matlab.ui.control.EditField
        InputOsimxFileEditFieldLabel    matlab.ui.control.Label
        InputOsimxFileSearchButton      matlab.ui.control.Button
        InputModelFileError             matlab.ui.control.Image
        InputModelFileEditField         matlab.ui.control.EditField
        InputModelFileEditFieldLabel    matlab.ui.control.Label
        InputModelFileSearchButton      matlab.ui.control.Button
        MuscleGroupsTab                 matlab.ui.container.Tab
        EditFiberLengthGroupsButton     matlab.ui.control.Button
        EditActivationGroupsButton      matlab.ui.control.Button
        ActivationMuscleGroupsTextArea  matlab.ui.control.TextArea
        ActivationMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        NormalizedFiberLengthMuscleGroupsTextArea  matlab.ui.control.TextArea
        NormalizedFiberLengthMuscleGroupsTextAreaLabel  matlab.ui.control.Label
        NCPTaskTab                      matlab.ui.container.Tab
        MTPTasksCostTermsTable_2        matlab.ui.control.Table
        MTPTasksMaxAllowableErrorEditField  matlab.ui.control.NumericEditField
        MaxAllowableErrorEditField_2Label  matlab.ui.control.Label
        MTPTasksCostTermsTable          matlab.ui.control.Table
        AuxiliaryTab                    matlab.ui.container.Tab
        AdvancedSettingsLabel           matlab.ui.control.Label
        AuxAdvancedSettingsTable        matlab.ui.control.Table
        AuxCostTermPanel                matlab.ui.container.Panel
        AuxCostTermsTable               matlab.ui.control.Table
        AuxErrorCenterEditField         matlab.ui.control.NumericEditField
        ErrorCenterEditFieldLabel       matlab.ui.control.Label
        AuxMaxAllowableErrorEditField   matlab.ui.control.NumericEditField
        MaxAllowableErrorEditFieldLabel  matlab.ui.control.Label
        SynxButton                      matlab.ui.control.Button
        MTLIButton                      matlab.ui.control.Button
        AuxiliaryToolsTab               matlab.ui.container.TabGroup
        MuscleTendonLengthInitializationTab  matlab.ui.container.Tab
        EditCostTermsLabel              matlab.ui.control.Label
        MaxNormalizedFiberLengthEditField  matlab.ui.control.NumericEditField
        MaxNormalizedFiberLengthEditFieldLabel  matlab.ui.control.Label
        MinNormalizedFiberLengthEditField  matlab.ui.control.NumericEditField
        MinNormalizedFiberLengthEditFieldLabel  matlab.ui.control.Label
        PassiveDataDirectoryEditField   matlab.ui.control.EditField
        PassiveDataDirectoryEditFieldLabel  matlab.ui.control.Label
        PassiveDataDirectorySearchButton  matlab.ui.control.Button
        PassiveDataDirectoryError       matlab.ui.control.Image
        EnableMTLICheckBox              matlab.ui.control.CheckBox
        SynergyExtrapolationTab         matlab.ui.container.Tab
        NumSynergiesSpinnerLabel        matlab.ui.control.Label
        NumSynergiesSpinner             matlab.ui.control.Spinner
        EnableSynergyExtrapolationCheckBox  matlab.ui.control.CheckBox
        PassiveDataInputDirectoryEditFieldLabel  matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        NeuralControlModelPersonalizationToolLabel  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        RenameMenu                      matlab.ui.container.Menu
        CopyMenu                        matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = private, SetObservable)
        model_coordinates string = [];
        model_groups string = [];

        input_model_file string = "";
        input_osimx_file string = "";
        data_directory string = "";
        mtp_results_directory string = "";
        results_directory string = "";
        coordinate_list string = [];
        trial_prefixes string = [];
        v_max_factor double = 10;

        activation_muscle_groups string = [];
        normalized_fiber_length_muscle_groups string = [];

        MuscleTendonLengthInitialization handle = ...
            MuscleTendonLengthInitializationClass();

        RCNLCostTerm cell = cell(0)


        advancedSettingNames = ...
            ["v_max_factor"
            "max_iterations"
            "max_function_evaluations"
            "step_tolerance"
            "function_tolerance"
            "optimality_tolerance"
            "diff_min_change"]

        advancedSettingValues = ...
            [10
            1000
            100000000
            1e-6
            1e-6
            1e-6
            0.0001]
    end

    properties(Access = private)  % listner properties
        InputModelFileListener
        OsimXFileListener
        DataDirectoryListener
        MtpDataDirectoryListener
        ResultsDirectoryListener
        CoordinateListListener
        trialPrefixesListener
        ActivationGroupsListener
        FiberLengthGroupsListener
        MissingEmgGroupsListener
        CollectedEmgGroupsListener
        PassiveDataDirectoryListener
        MaxFiberLengthListener
        MinFiberLengthListener
        taskIndexListener
        advancedSettingsListener
        selectedTabListener
    end

    methods (Access = private) % listener methods
        function makeListeners(app)
            app.InputModelFileListener = addlistener(app, ...
                'input_model_file', 'PostSet', ...
                @(src,event)InputModelFileListenerFunction(app));

            app.OsimXFileListener = addlistener(app, ...
                'input_osimx_file', 'PostSet', ...
                @(src,event)OsimXFileListenerFunction(app));

            app.DataDirectoryListener = addlistener(app, ...
                'data_directory', 'PostSet', ...
                @(src,event)DataDirectoryListenerFunction(app));

            app.MtpDataDirectoryListener = addlistener(app, ...
                'mtp_results_directory', 'PostSet', ...
                @(src,event)MtpResultsDirectoryListenerFunction(app));

            app.ResultsDirectoryListener = addlistener(app, ...
                'results_directory', 'PostSet', ...
                @(src,event)ResultsDirectoryListenerFunction(app));

            app.CoordinateListListener = addlistener(app, ...
                'coordinate_list', 'PostSet', ...
                @(src,event)CoordinateListListenerFunction(app));

            app.trialPrefixesListener = addlistener(app, ...
                'trial_prefixes', 'PostSet', ...
                @(src, event)updateTrialPrefixes(app));

            app.ActivationGroupsListener = addlistener(app, ...
                'activation_muscle_groups', 'PostSet', ...
                @(src,event)ActivationGroupsListenerFunction(app));

            app.FiberLengthGroupsListener = addlistener(app, ...
                'normalized_fiber_length_muscle_groups', 'PostSet', ...
                @(src,event)FiberLengthGroupsListenerFunction(app));

            app.PassiveDataDirectoryListener = addlistener(...
                app.MuscleTendonLengthInitialization, ...
                'passive_data_input_directory', 'PostSet', ...
                @(src,event)updatePassiveDataDirectory(app));

            app.MaxFiberLengthListener = addlistener(...
                app.MuscleTendonLengthInitialization, ...
                'max_normalized_muscle_fiber_length', 'PostSet', ...
                @(src,event)maxFiberLengthListenerFunction(app));

            app.MinFiberLengthListener = addlistener(...
                app.MuscleTendonLengthInitialization, ...
                'min_normalized_muscle_fiber_length', 'PostSet', ...
                @(src,event)minFiberLengthListenerFunction(app));

            app.advancedSettingsListener = addlistener(app, ...
                'advancedSettingValues', 'PostSet', ...
                @(src, event)updateAdvancedSettingValues(app));

            app.selectedTabListener = addlistener(app.TabGroup, ...
                'SelectedTab', 'PostSet', ...
                @(src, event)formatTabButtons(app));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: InputsButton
        function InputsButtonPushed(app, event)

        end

        % Button pushed function: NCPTaskButton
        function NCPTaskButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.NCPTaskTab;
            app.formatTabButtons()
        end

        % Button pushed function: AdvancedButton
        function AdvancedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AdvancedTab;
            app.formatTabButtons()
        end

        % Button pushed function: AuxiliaryToolsButton
        function AuxiliaryToolsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AuxiliaryTab;
            app.formatTabButtons()
        end

        % Button pushed function: MuscleGroupsButton
        function MuscleGroupsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MuscleGroupsTab;
            app.formatTabButtons()
        end

        % Button pushed function: InputModelFileSearchButton
        function InputModelFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osim', "Select an OpenSim Model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_model_file = fullfile(path, file);
        end

        % Button pushed function: InputOsimxFileSearchButton
        function InputOsimxFileSearchButtonPushed(app, event)
            [file, path] = uigetfile('*.osimx', "Select an Osimx model File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.input_osimx_file = fullfile(path, file);
        end

        % Button pushed function: ResultsDirectorySearchButton
        function ResultsDirectorySearchButtonPushed(app, event)
            folder = uigetdir("Select a folder to store your results");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.results_directory = folder;
        end

        % Button pushed function: InputDataSearchButton
        function InputDataSearchButtonPushed(app, event)
            folder = uigetdir("Select your input data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.data_directory = folder;
        end

        % Button pushed function: CoordinateListEditButton
        function CoordinateListEditButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_coordinates, ...
                app.coordinate_list)
            app.objectSelectionType = 'coordinate';
        end

        % Value changed function: InputModelFileEditField
        function InputModelFileEditFieldValueChanged(app, event)
            app.input_model_file = GetFullPath( ...
                app.InputModelFileEditField.Value);
        end

        % Value changed function: InputOsimxFileEditField
        function InputOsimxFileEditFieldValueChanged(app, event)
            app.input_osimx_file = GetFullPath( ...
                app.InputOsimxFileEditField.Value);
        end

        % Value changed function: InputDataEditField
        function InputDataEditFieldValueChanged(app, event)
            app.data_directory = GetFullPath( ...
                app.InputDataEditField.Value);
        end

        % Value changed function: NCPResultsDirectoryEditField
        function NCPResultsDirectoryEditFieldValueChanged(app, event)
            app.results_directory = GetFullPath( ...
                app.NCPResultsDirectoryEditField.Value);
        end

        % Button pushed function: EditActivationGroupsButton
        function EditActivationGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.activation_muscle_groups)
            app.objectSelectionType = 'activationGroup';
        end

        % Button pushed function: EditFiberLengthGroupsButton
        function EditFiberLengthGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.normalized_fiber_length_muscle_groups)
            app.objectSelectionType = 'fiberLengthGroup';
        end

        % Callback function: not associated with a component
        function EditMissingEmgGroupsButtonPushed(app, event)
            ObjectSelectionWindow(app, app.model_groups, ...
                app.missing_emg_channel_muscle_groups)
            app.objectSelectionType = 'missingEmgGroup';
        end

        % Callback function: not associated with a component
        function EditCollectedEmgGroupsButtonPushed(app, event)
            %% should read directly from EMG file
            
            ObjectSelectionWindow(app, app.emgLabels, ...
                app.collected_emg_channel_muscle_groups)
            app.objectSelectionType = 'collectedEmgGroup';
        end

        % Callback function
        function PasssiveDataSearchButtonPushed(app, event)
            
        end

        % Callback function
        function PassiveDataInputDirectoryEditFieldValueChanged(app, event)
            app.passive_data_input_directory = GetFullPath( ...
                app.PassiveDataInputDirectoryEditField.Value);
        end

        % Value changed function: EnableMTLICheckBox
        function EnableMTLICheckBoxValueChanged(app, event)
            if app.EnableMTLICheckBox.Value
                app.MuscleTendonLengthInitialization.is_enabled = "true";
            else
                app.MuscleTendonLengthInitialization.is_enabled = "false";
            end
            
        end

        % Value changed function: EnableSynergyExtrapolationCheckBox
        function EnableSynergyExtrapolationCheckBoxValueChanged(app, event)
            if app.EnableSynergyExtrapolationCheckBox.Value
                app.MTPSynergyExtrapolation.is_enabled = "true";
            else
                app.MTPSynergyExtrapolation.is_enabled = "false";
            end
            
        end

        % Value changed function: NumSynergiesSpinner
        function NumSynergiesSpinnerValueChanged(app, event)
            app.MTPSynergyExtrapolation.number_of_synergies = app.NumSynergiesSpinner.Value;
        end

        % Button pushed function: LoadSettingsFileButton
        function LoadSettingsFileButtonPushed(app, event)
            [file, path] = uigetfile('*.xml', "Load XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.loadSettingsFile(fullfile(path, file));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile('*.xml', "Save XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.saveSettingsFile(fullfile(path, file))
            % app.currentSettingsFile = fullfile(path, file);
            app.needToSave = false;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web("https://nmsm.rice.edu/guides-and-publications/tool-overviews/model-personalization/muscle-tendon-personalization/")
        end

        % Image clicked function: RcnlLogo
        function RcnlLogoImageClicked(app, event)
            web("https://nmsm.rice.edu/")
        end

        % Callback function: not associated with a component
        function DesignVariablesTableCellEdit(app, event)
            indices = event.Indices;
            isEnabled = app.DesignVariablesTable.Data.isEnabled(indices(1));
            if isEnabled
                app.MTPTask{app.taskIndex}.setParameterValueByIndex(... 
                    indices(1), 'true')
            else
                app.MTPTask{app.taskIndex}.setParameterValueByIndex(... 
                    indices(1), 'false')
            end
        end

        % Cell edit callback: MTPTasksCostTermsTable
        function MTPTasksCostTermsTableCellEdit(app, event)
            indices = event.Indices;
            isEnabled = app.MTPTasksCostTermsTable.Data.isEnabled(indices(1));
            if isEnabled
                app.MTPTask{app.taskIndex}.RCNLCostTerm{indices(1)}. ...
                    is_enabled = 'true';
            else
                app.MTPTask{app.taskIndex}.RCNLCostTerm{indices(1)}. ...
                    is_enabled = 'false';
            end
        end

        % Callback function: not associated with a component
        function TasksTableSelectionChanged(app, event)
            if isempty(app.TasksTable.Selection)
                return
            end

            if app.TasksTable.Selection == height(app.TasksTable.Data) % if last element
                app.createDefaultTask();
                return
            else
                app.taskIndex = app.TasksTable.Selection;
            end
        end

        % Selection changed function: MTPTasksCostTermsTable
        function MTPTasksCostTermsTableSelectionChanged(app, event)
            costTermIndex = app.MTPTasksCostTermsTable.Selection;
            app.MTPTasksErrorCenterEditField.Value = ...
                app.MTPTask{app.taskIndex}.RCNLCostTerm{costTermIndex}. ...
                error_center;
            app.MTPTasksMaxAllowableErrorEditField.Value = ...
                app.MTPTask{app.taskIndex}.RCNLCostTerm{costTermIndex}. ...
                max_allowable_error;
        end

        % Callback function: not associated with a component
        function MTPTasksMaxAllowableErrorEditFieldValueChanged(app, event)
            costTermIndex = app.MTPTasksCostTermsTable.Selection;
            value = app.MTPTasksMaxAllowableErrorEditField.Value;
            app.MTPTask{app.taskIndex}.RCNLCostTerm{costTermIndex}. ...
                max_allowable_error = value;
        end

        % Callback function: not associated with a component
        function MTPTasksErrorCenterEditFieldValueChanged(app, event)
            costTermIndex = app.MTPTasksCostTermsTable.Selection;
            value = app.MTPTasksErrorCenterEditField.Value;
            app.MTPTask{app.taskIndex}.RCNLCostTerm{costTermIndex}. ...
                error_center = value;
        end

        % Callback function: not associated with a component
        function TasksTableCellEdit(app, event)
            indices = event.Indices;
            app.taskIndex = indices(1);
            if indices(2) == 1
                if app.TasksTable.Data.isEnabled(indices(1))
                    app.MTPTask{app.taskIndex}.is_enabled = 'true';
                else
                    app.MTPTask{app.taskIndex}.is_enabled = 'false';
                end
            end
            if indices(2) == 2
                app.MTPTask{app.taskIndex}.name = event.NewData;
            end
            app.updateMTPTasksListBox()
        end

        % Callback function: not associated with a component
        function MoveTaskUpButtonPushed(app, event)
            app.moveTaskUp()
        end

        % Callback function: not associated with a component
        function MoveTaskDownButtonPushed(app, event)
            app.moveTaskDown()
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            oldName = app.MTPTask{app.taskIndex}.name;
            newName = inputdlg("Rename task:", "Rename", [1 40], {oldName});
            if isempty(newName)
                return
            end
            app.MTPTask{app.taskIndex}.name = newName;
            app.updateMTPTasksListBox()
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            deletionIndex = 1 : 1 : length(app.MTPTask) == ...
                app.TasksTable.Selection;
            app.deleteTask(deletionIndex);
        end

        % Button pushed function: SynxButton
        function SynxButtonPushed(app, event)
            app.AuxiliaryToolsTab.SelectedTab = app.SynergyExtrapolationTab;
            app.formatTabButtons()
            app.updateAuxPanel()
        end

        % Button pushed function: MTLIButton
        function MTLIButtonPushed(app, event)
            app.AuxiliaryToolsTab.SelectedTab = app.MuscleTendonLengthInitializationTab;
            app.formatTabButtons()
            app.updateAuxPanel()
        end

        % Value changed function: MaxNormalizedFiberLengthEditField
        function MaxNormalizedFiberLengthEditFieldValueChanged(app, event)
            value = app.MaxNormalizedFiberLengthEditField.Value;
            app.MuscleTendonLengthInitialization.max_normalized_muscle_fiber_length = value;
        end

        % Value changed function: MinNormalizedFiberLengthEditField
        function MinNormalizedFiberLengthEditFieldValueChanged(app, event)
            value = app.MinNormalizedFiberLengthEditField.Value;
            app.MuscleTendonLengthInitialization.min_normalized_muscle_fiber_length = value;
        end

        % Button pushed function: PassiveDataDirectorySearchButton
        function PassiveDataDirectorySearchButtonPushed(app, event)
            folder = uigetdir("Select your passive data folder");
            % User hit "Cancel"
            if isequal(folder, 0)
                return
            end
            app.MuscleTendonLengthInitialization.passive_data_input_directory = folder;
        end

        % Cell edit callback: AuxAdvancedSettingsTable
        function AuxAdvancedSettingsTableCellEdit(app, event)
            indices = event.Indices;
            switch app.AuxiliaryToolsTab.SelectedTab
                case app.SynergyExtrapolationTab
                    app.MTPSynergyExtrapolation.setParameterValueByIndex(...
                        indices(1), app.AuxAdvancedSettingsTable.Data(indices(1), indices(2)).value);
                case app.MuscleTendonLengthInitializationTab
                    app.MuscleTendonLengthInitialization.setParameterValueByIndex(...
                        indices(1), app.AuxAdvancedSettingsTable.Data(indices(1), indices(2)).value);
            end
            
        end

        % Cell edit callback: AuxCostTermsTable
        function AuxCostTermsTableCellEdit(app, event)
            indices = event.Indices;
            isEnabled = app.AuxCostTermsTable.Data.isEnabled(indices(1));
            switch app.AuxiliaryToolsTab.SelectedTab
                case app.SynergyExtrapolationTab
                    if isEnabled
                        app.MTPSynergyExtrapolation.RCNLCostTerm{indices(1)}. ...
                            is_enabled = "true";
                    else
                        app.MTPSynergyExtrapolation.RCNLCostTerm{indices(1)}. ...
                            is_enabled = "false";
                    end
                case app.MuscleTendonLengthInitializationTab
                    if isEnabled
                        app.MuscleTendonLengthInitialization.RCNLCostTerm{indices(1)}. ...
                            is_enabled = "true";
                    else
                        app.MuscleTendonLengthInitialization.RCNLCostTerm{indices(1)}. ...
                            is_enabled = "false";
                    end
            end
        end

        % Selection changed function: AuxCostTermsTable
        function AuxCostTermsTableSelectionChanged(app, event)
            costTermIndex = app.AuxCostTermsTable.Selection;
            switch app.AuxiliaryToolsTab.SelectedTab
                case app.SynergyExtrapolationTab
                    app.AuxErrorCenterEditField.Value = ...
                        app.MTPSynergyExtrapolation.RCNLCostTerm{costTermIndex}. ...
                        error_center;
                    app.AuxMaxAllowableErrorEditField.Value = ...
                        app.MTPSynergyExtrapolation.RCNLCostTerm{costTermIndex}. ...
                        max_allowable_error;
                case app.MuscleTendonLengthInitializationTab
                    app.AuxErrorCenterEditField.Value = ...
                        app.MuscleTendonLengthInitialization.RCNLCostTerm{costTermIndex}. ...
                        error_center;
                    app.AuxMaxAllowableErrorEditField.Value = ...
                        app.MuscleTendonLengthInitialization.RCNLCostTerm{costTermIndex}. ...
                        max_allowable_error;
            end
        end

        % Value changed function: AuxMaxAllowableErrorEditField
        function AuxMaxAllowableErrorEditFieldValueChanged(app, event)
            value = app.AuxMaxAllowableErrorEditField.Value;
            costTermIndex = app.AuxCostTermsTable.Selection;
            switch app.AuxiliaryToolsTab.SelectedTab
                case app.SynergyExtrapolationTab
                    app.MTPSynergyExtrapolation.RCNLCostTerm{costTermIndex}. ...
                        max_allowable_error = value;
                case app.MuscleTendonLengthInitializationTab
                    app.MuscleTendonLengthInitialization.RCNLCostTerm{costTermIndex}. ...
                        max_allowable_error = value;
            end
        end

        % Value changed function: AuxErrorCenterEditField
        function AuxErrorCenterEditFieldValueChanged(app, event)
            value = app.AuxErrorCenterEditField.Value;
            costTermIndex = app.AuxCostTermsTable.Selection;
            switch app.AuxiliaryToolsTab.SelectedTab
                case app.SynergyExtrapolationTab
                    app.MTPSynergyExtrapolation.RCNLCostTerm{costTermIndex}. ...
                        error_center = value;
                case app.MuscleTendonLengthInitializationTab
                    app.MuscleTendonLengthInitialization.RCNLCostTerm{costTermIndex}. ...
                        error_center = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NeuralControlModelPersonalizationToolLabel = uilabel(app.UIFigure);
            app.NeuralControlModelPersonalizationToolLabel.Text = 'Neural Control Model Personalization Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputModelFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputModelFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputModelFileSearchButtonPushed, true);
            app.InputModelFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputModelFileEditFieldLabel.Text = 'Input Model File';
            app.InputModelFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputModelFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputModelFileEditFieldValueChanged, true);
            app.InputModelFileError = uiimage(app.InputsTab);
            app.InputModelFileError.Visible = 'off';
            app.InputModelFileError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputOsimxFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputOsimxFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputOsimxFileSearchButtonPushed, true);
            app.InputOsimxFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputOsimxFileSearchButton.Text = '';
            app.InputOsimxFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputOsimxFileEditFieldLabel.Text = 'Input Osimx File';
            app.InputOsimxFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputOsimxFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputOsimxFileEditFieldValueChanged, true);
            app.InputOsimxFileWarning = uiimage(app.InputsTab);
            app.InputOsimxFileWarning.Visible = 'off';
            app.InputOsimxFileWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.InputDataSearchButton = uibutton(app.InputsTab, 'push');
            app.InputDataSearchButton.ButtonPushedFcn = createCallbackFcn(app, @InputDataSearchButtonPushed, true);
            app.InputDataSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputDataSearchButton.Text = '';
            app.InputDataEditField = uieditfield(app.InputsTab, 'text');
            app.InputDataEditField.ValueChangedFcn = createCallbackFcn(app, @InputDataEditFieldValueChanged, true);
            app.InputDataError = uiimage(app.InputsTab);
            app.InputDataError.Visible = 'off';
            app.InputDataError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.ResultsDirectorySearchButton = uibutton(app.InputsTab, 'push');
            app.ResultsDirectorySearchButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsDirectorySearchButtonPushed, true);
            app.ResultsDirectorySearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.ResultsDirectorySearchButton.Text = '';
            app.NCPResultsDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.NCPResultsDirectoryEditFieldLabel.Text = 'NCP Results Directory';
            app.NCPResultsDirectoryEditField = uieditfield(app.InputsTab, 'text');
            app.NCPResultsDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @NCPResultsDirectoryEditFieldValueChanged, true);
            app.ResultsDirectoryError = uiimage(app.InputsTab);
            app.ResultsDirectoryError.Visible = 'off';
            app.ResultsDirectoryError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputDataDirectoryEditFieldLabel = uilabel(app.InputsTab);
            app.InputDataDirectoryEditFieldLabel.Text = 'Input Data Directory';
            app.CoordinatesListTextArea = uitextarea(app.InputsTab);
            app.CoordinatesListTextArea.Editable = 'off';
            app.CoordinatesListTextAreaLabel = uilabel(app.InputsTab);
            app.CoordinatesListTextAreaLabel.Text = 'Coordinates List';
            app.CoordinateListWarning = uiimage(app.InputsTab);
            app.CoordinateListWarning.Visible = 'off';
            app.CoordinateListWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.CoordinateListEditButton = uibutton(app.InputsTab, 'push');
            app.CoordinateListEditButton.ButtonPushedFcn = createCallbackFcn(app, @CoordinateListEditButtonPushed, true);
            app.CoordinateListEditButton.Text = 'Edit';
            app.TaskPrefixesEditFieldLabel = uilabel(app.InputsTab);
            app.TaskPrefixesEditFieldLabel.Text = 'Task Prefixes';
            app.TaskPrefixesEditField = uieditfield(app.InputsTab, 'text');
            app.TaskPrefixesWarning = uiimage(app.InputsTab);
            app.TaskPrefixesWarning.Visible = 'off';
            app.TaskPrefixesWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.TaskPrefixesError = uiimage(app.InputsTab);
            app.TaskPrefixesError.Visible = 'off';
            app.TaskPrefixesError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.MTPResultsDirError = uiimage(app.InputsTab);
            app.MTPResultsDirError.Visible = 'off';
            app.MTPResultsDirError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.MTPResultsDirSearchButton = uibutton(app.InputsTab, 'push');
            app.MTPResultsDirSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.MTPResultsDirSearchButton.Text = '';
            app.MTPResultsDirEditField = uieditfield(app.InputsTab, 'text');
            app.MTPResultsDirectoryLabel = uilabel(app.InputsTab);
            app.MTPResultsDirectoryLabel.Text = 'MTP Results Directory';
            app.MuscleGroupsTab = uitab(app.TabGroup);
            app.NormalizedFiberLengthMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.NormalizedFiberLengthMuscleGroupsTextAreaLabel.Text = 'Normalized Fiber Length Muscle Groups';
            app.NormalizedFiberLengthMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.NormalizedFiberLengthMuscleGroupsTextArea.Editable = 'off';
            app.ActivationMuscleGroupsTextAreaLabel = uilabel(app.MuscleGroupsTab);
            app.ActivationMuscleGroupsTextAreaLabel.Text = 'Activation Muscle Groups';
            app.ActivationMuscleGroupsTextArea = uitextarea(app.MuscleGroupsTab);
            app.ActivationMuscleGroupsTextArea.Editable = 'off';
            app.EditActivationGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditActivationGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditActivationGroupsButtonPushed, true);
            app.EditActivationGroupsButton.Text = 'Edit';
            app.EditFiberLengthGroupsButton = uibutton(app.MuscleGroupsTab, 'push');
            app.EditFiberLengthGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @EditFiberLengthGroupsButtonPushed, true);
            app.EditFiberLengthGroupsButton.Text = 'Edit';
            app.NCPTaskTab = uitab(app.TabGroup);
            app.MTPTasksCostTermsTable = uitable(app.NCPTaskTab);
            app.MTPTasksCostTermsTable.ColumnName = {''; 'Cost Term'};
            app.MTPTasksCostTermsTable.RowName = {};
            app.MTPTasksCostTermsTable.SelectionType = 'row';
            app.MTPTasksCostTermsTable.ColumnEditable = [true false];
            app.MTPTasksCostTermsTable.CellEditCallback = createCallbackFcn(app, @MTPTasksCostTermsTableCellEdit, true);
            app.MTPTasksCostTermsTable.SelectionChangedFcn = createCallbackFcn(app, @MTPTasksCostTermsTableSelectionChanged, true);
            app.MTPTasksCostTermsTable.Multiselect = 'off';
            app.MaxAllowableErrorEditField_2Label = uilabel(app.NCPTaskTab);
            app.MaxAllowableErrorEditField_2Label.Text = 'Max Allowable Error';
            app.MTPTasksMaxAllowableErrorEditField = uieditfield(app.NCPTaskTab, 'numeric');
            app.MTPTasksMaxAllowableErrorEditField.AllowEmpty = 'on';
            app.MTPTasksMaxAllowableErrorEditField.Value = [];
            app.MTPTasksCostTermsTable_2 = uitable(app.NCPTaskTab);
            app.MTPTasksCostTermsTable_2.ColumnName = {'Num Synergies'; 'Cost Term'};
            app.MTPTasksCostTermsTable_2.RowName = {};
            app.MTPTasksCostTermsTable_2.SelectionType = 'row';
            app.MTPTasksCostTermsTable_2.ColumnEditable = [true false];
            app.MTPTasksCostTermsTable_2.Multiselect = 'off';
            app.AuxiliaryTab = uitab(app.TabGroup);
            app.PassiveDataInputDirectoryEditFieldLabel = uilabel(app.AuxiliaryTab);
            app.PassiveDataInputDirectoryEditFieldLabel.Text = 'Passive Data Input Directory';
            app.AuxiliaryToolsTab = uitabgroup(app.AuxiliaryTab);
            app.MuscleTendonLengthInitializationTab = uitab(app.AuxiliaryToolsTab);
            app.MuscleTendonLengthInitializationTab.Title = 'Tab';
            app.EnableMTLICheckBox = uicheckbox(app.MuscleTendonLengthInitializationTab);
            app.EnableMTLICheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableMTLICheckBoxValueChanged, true);
            app.EnableMTLICheckBox.Text = 'Enable Muscle-tendon Length Initialization';
            app.PassiveDataDirectoryError = uiimage(app.MuscleTendonLengthInitializationTab);
            app.PassiveDataDirectoryError.Visible = 'off';
            app.PassiveDataDirectoryError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.PassiveDataDirectorySearchButton = uibutton(app.MuscleTendonLengthInitializationTab, 'push');
            app.PassiveDataDirectorySearchButton.ButtonPushedFcn = createCallbackFcn(app, @PassiveDataDirectorySearchButtonPushed, true);
            app.PassiveDataDirectorySearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.PassiveDataDirectorySearchButton.Text = '';
            app.PassiveDataDirectoryEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.PassiveDataDirectoryEditFieldLabel.Text = 'Passive Data Directory';
            app.PassiveDataDirectoryEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'text');
            app.MinNormalizedFiberLengthEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.MinNormalizedFiberLengthEditFieldLabel.Text = 'Min Normalized Fiber Length';
            app.MinNormalizedFiberLengthEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'numeric');
            app.MinNormalizedFiberLengthEditField.Limits = [0 3];
            app.MinNormalizedFiberLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinNormalizedFiberLengthEditFieldValueChanged, true);
            app.MinNormalizedFiberLengthEditField.Value = 0.7;
            app.MaxNormalizedFiberLengthEditFieldLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.MaxNormalizedFiberLengthEditFieldLabel.Text = 'Max Normalized Fiber Length';
            app.MaxNormalizedFiberLengthEditField = uieditfield(app.MuscleTendonLengthInitializationTab, 'numeric');
            app.MaxNormalizedFiberLengthEditField.Limits = [0 3];
            app.MaxNormalizedFiberLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MaxNormalizedFiberLengthEditFieldValueChanged, true);
            app.MaxNormalizedFiberLengthEditField.Value = 1;
            app.EditCostTermsLabel = uilabel(app.MuscleTendonLengthInitializationTab);
            app.EditCostTermsLabel.Text = 'Edit Cost Terms';
            app.SynergyExtrapolationTab = uitab(app.AuxiliaryToolsTab);
            app.SynergyExtrapolationTab.Title = 'Tab2';
            app.EnableSynergyExtrapolationCheckBox = uicheckbox(app.SynergyExtrapolationTab);
            app.EnableSynergyExtrapolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSynergyExtrapolationCheckBoxValueChanged, true);
            app.EnableSynergyExtrapolationCheckBox.Text = 'Enable Synergy Extrapolation';
            app.NumSynergiesSpinner = uispinner(app.SynergyExtrapolationTab);
            app.NumSynergiesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumSynergiesSpinnerValueChanged, true);
            app.NumSynergiesSpinnerLabel = uilabel(app.SynergyExtrapolationTab);
            app.NumSynergiesSpinnerLabel.Text = 'Num Synergies';
            app.MTLIButton = uibutton(app.AuxiliaryTab, 'push');
            app.MTLIButton.ButtonPushedFcn = createCallbackFcn(app, @MTLIButtonPushed, true);
            app.MTLIButton.Text = 'Muscle Tendon Length Initialization';
            app.SynxButton = uibutton(app.AuxiliaryTab, 'push');
            app.SynxButton.ButtonPushedFcn = createCallbackFcn(app, @SynxButtonPushed, true);
            app.SynxButton.Text = 'Synergy Extrapolation';
            app.AuxCostTermPanel = uipanel(app.AuxiliaryTab);
            app.MaxAllowableErrorEditFieldLabel = uilabel(app.AuxCostTermPanel);
            app.MaxAllowableErrorEditFieldLabel.Text = 'Max Allowable Error';
            app.AuxMaxAllowableErrorEditField = uieditfield(app.AuxCostTermPanel, 'numeric');
            app.AuxMaxAllowableErrorEditField.ValueChangedFcn = createCallbackFcn(app, @AuxMaxAllowableErrorEditFieldValueChanged, true);
            app.ErrorCenterEditFieldLabel = uilabel(app.AuxCostTermPanel);
            app.ErrorCenterEditFieldLabel.Text = 'Error Center';
            app.AuxErrorCenterEditField = uieditfield(app.AuxCostTermPanel, 'numeric');
            app.AuxErrorCenterEditField.ValueChangedFcn = createCallbackFcn(app, @AuxErrorCenterEditFieldValueChanged, true);
            app.AuxCostTermsTable = uitable(app.AuxCostTermPanel);
            app.AuxCostTermsTable.ColumnName = {''; 'Cost Term'};
            app.AuxCostTermsTable.RowName = {};
            app.AuxCostTermsTable.ColumnSortable = [false true];
            app.AuxCostTermsTable.SelectionType = 'row';
            app.AuxCostTermsTable.ColumnEditable = true;
            app.AuxCostTermsTable.CellEditCallback = createCallbackFcn(app, @AuxCostTermsTableCellEdit, true);
            app.AuxCostTermsTable.SelectionChangedFcn = createCallbackFcn(app, @AuxCostTermsTableSelectionChanged, true);
            app.AuxCostTermsTable.Multiselect = 'off';
            app.AuxAdvancedSettingsTable = uitable(app.AuxiliaryTab);
            app.AuxAdvancedSettingsTable.ColumnName = {'Parameter'; 'Value'};
            app.AuxAdvancedSettingsTable.RowName = {};
            app.AuxAdvancedSettingsTable.SelectionType = 'row';
            app.AuxAdvancedSettingsTable.ColumnEditable = [false true];
            app.AuxAdvancedSettingsTable.CellEditCallback = createCallbackFcn(app, @AuxAdvancedSettingsTableCellEdit, true);
            app.AdvancedSettingsLabel = uilabel(app.AuxiliaryTab);
            app.AdvancedSettingsLabel.Text = 'Advanced Settings';
            app.AdvancedTab = uitab(app.TabGroup);
            app.Mask1 = uiimage(app.UIFigure);
            app.Mask1.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'greyMask.png');
            app.MTPImage = uiimage(app.UIFigure);
            app.MTPImage.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'ncpFigure.png');
            app.MuscleGroupsButton = uibutton(app.UIFigure, 'push');
            app.MuscleGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @MuscleGroupsButtonPushed, true);
            app.MuscleGroupsButton.Text = 'Muscle Groups';
            app.AuxiliaryToolsButton = uibutton(app.UIFigure, 'push');
            app.AuxiliaryToolsButton.ButtonPushedFcn = createCallbackFcn(app, @AuxiliaryToolsButtonPushed, true);
            app.AuxiliaryToolsButton.Text = 'Auxiliary Tools';
            app.AdvancedButton = uibutton(app.UIFigure, 'push');
            app.AdvancedButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedButtonPushed, true);
            app.AdvancedButton.Text = 'Advanced';
            app.InputsButton = uibutton(app.UIFigure, 'push');
            app.InputsButton.ButtonPushedFcn = createCallbackFcn(app, @InputsButtonPushed, true);
            app.InputsButton.Text = 'NCP Inputs';
            app.RcnlLogo = uiimage(app.UIFigure);
            app.RcnlLogo.ImageClickedFcn = createCallbackFcn(app, @RcnlLogoImageClicked, true);
            app.RcnlLogo.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'rcnlIcon.png');
            app.LoadSettingsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsFileButtonPushed, true);
            app.LoadSettingsFileButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.NCPTaskButton = uibutton(app.UIFigure, 'push');
            app.NCPTaskButton.ButtonPushedFcn = createCallbackFcn(app, @NCPTaskButtonPushed, true);
            app.NCPTaskButton.Text = 'NCP Task';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.Text = 'Copy';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NCPBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end