classdef TreatmentOptimizationBase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResetButton                     matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        LoadSettingsFileButton          matlab.ui.control.Button
        RcnlLogo                        matlab.ui.control.Image
        InputsButton                    matlab.ui.control.Button
        SolverSettingsButton            matlab.ui.control.Button
        ControllersButton               matlab.ui.control.Button
        CostTermsButton                 matlab.ui.control.Button
        ConstraintTermsButton           matlab.ui.control.Button
        AdvancedButton                  matlab.ui.control.Button
        MTPImage                        matlab.ui.control.Image
        Mask1                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        ToolSelectionDropDown           matlab.ui.control.DropDown
        ToolSelectionDropDownLabel      matlab.ui.control.Label
        MTPResultsDirectoryLabel        matlab.ui.control.Label
        MTPResultsDirEditField          matlab.ui.control.EditField
        InitialGuessSearchButton        matlab.ui.control.Button
        MTPResultsDirError              matlab.ui.control.Image
        TaskPrefixesError               matlab.ui.control.Image
        TaskPrefixesWarning             matlab.ui.control.Image
        TrialPrefixEditField            matlab.ui.control.EditField
        TrialPrefixEditFieldLabel       matlab.ui.control.Label
        CoordinateListEditButton        matlab.ui.control.Button
        CoordinateListWarning           matlab.ui.control.Image
        StatesCoordinatesListTextAreaLabel  matlab.ui.control.Label
        StatesCoordinatesListTextArea   matlab.ui.control.TextArea
        TrackedQuantitiesDirectoryLabel  matlab.ui.control.Label
        InputDataError                  matlab.ui.control.Image
        InputDataEditField              matlab.ui.control.EditField
        TrackedQuantitiesSearchButton   matlab.ui.control.Button
        InputOsimxFileWarning           matlab.ui.control.Image
        InputOsimxFileEditField         matlab.ui.control.EditField
        InputOsimxFileEditFieldLabel    matlab.ui.control.Label
        InputOsimxFileSearchButton      matlab.ui.control.Button
        InputModelFileError             matlab.ui.control.Image
        InputModelFileEditField         matlab.ui.control.EditField
        InputModelFileEditFieldLabel    matlab.ui.control.Label
        InputModelFileSearchButton      matlab.ui.control.Button
        SolverSettingsTab               matlab.ui.container.Tab
        ControllersTab                  matlab.ui.container.Tab
        TorqueControlsButton            matlab.ui.control.Button
        SynergyControlsButton           matlab.ui.control.Button
        UserDefinedControlsButton       matlab.ui.control.Button
        Mask1_2                         matlab.ui.control.Image
        ControllersTabGroup             matlab.ui.container.TabGroup
        TorqueTab                       matlab.ui.container.Tab
        TorqueControllerAdvancedSettings  matlab.ui.control.Label
        AuxAdvancedSettingsTable        matlab.ui.control.Table
        CoordinatesListTextAreaLabel    matlab.ui.control.Label
        TorqueControllerCoordinateList  matlab.ui.control.TextArea
        TorqueControllerCoordianteListError  matlab.ui.control.Image
        TorqueControllerCoordinateEditButton  matlab.ui.control.Button
        UseRCNLTorqueControllerCheckBox  matlab.ui.control.CheckBox
        SynergyTab                      matlab.ui.container.Tab
        AdvancedSettingsLabel_2         matlab.ui.control.Label
        AuxAdvancedSettingsTable_3      matlab.ui.control.Table
        TorqueControllerCoordinateList_3  matlab.ui.control.TextArea
        SurrogateModelCoordinatesListLabel_2  matlab.ui.control.Label
        TorqueControllerCoordinateEditButton_3  matlab.ui.control.Button
        AdvancedSettingsLabel           matlab.ui.control.Label
        AuxAdvancedSettingsTable_2      matlab.ui.control.Table
        OptimizeSynergyVectorsCheckBox  matlab.ui.control.CheckBox
        SynergyVectorNormalizationMethodDropDown  matlab.ui.control.DropDown
        SynergyVectorNormalizationMethodLabel  matlab.ui.control.Label
        UseRCNLMuscleControllerCheckBox  matlab.ui.control.CheckBox
        TorqueControllerCoordinateList_2  matlab.ui.control.TextArea
        SurrogateModelCoordinatesListLabel  matlab.ui.control.Label
        TorqueControllerCoordianteListError_2  matlab.ui.control.Image
        TorqueControllerCoordinateEditButton_2  matlab.ui.control.Button
        UseRCNLSynergyControllerCheckBox  matlab.ui.control.CheckBox
        MuscleTab                       matlab.ui.container.Tab
        UserDefinedTab                  matlab.ui.container.Tab
        CostTermsTab                    matlab.ui.container.Tab
        ConstraintTermsTab              matlab.ui.container.Tab
        AdvancedTab                     matlab.ui.container.Tab
        TreatmentOptimizationLabel      matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        RenameMenu                      matlab.ui.container.Menu
        CopyMenu                        matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
    end

    
    properties (Access = private, SetObservable)
        
    end

    properties(Access = private)  % listner properties

    end

    methods (Access = private) % listener methods
        function makeListeners(app)
            
        end

        function formatTabButtons(app) % good
            
        end
    end

    methods(Access=public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LoadSettingsFileButton
        function LoadSettingsFileButtonPushed(app, event)
            [file, path] = uigetfile('*.xml', "Load XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.loadSettingsFile(fullfile(path, file));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile('*.xml', "Save XML Settings File");
            % User hit "Cancel"
            if isequal(file, 0)
                return
            end
            app.saveSettingsFile(fullfile(path, file))
            % app.currentSettingsFile = fullfile(path, file);
            app.needToSave = false;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web("https://nmsm.rice.edu/guides-and-publications/tool-overviews/model-personalization/muscle-tendon-personalization/")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TreatmentOptimizationLabel = uilabel(app.UIFigure);
            app.TreatmentOptimizationLabel.Text = 'Treatment Optimization';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputModelFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputModelFileSearchButton.Text = '';
            app.InputModelFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputModelFileEditFieldLabel.Text = 'Input Model File';
            app.InputModelFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputModelFileError = uiimage(app.InputsTab);
            app.InputModelFileError.Visible = 'off';
            app.InputModelFileError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InputOsimxFileSearchButton = uibutton(app.InputsTab, 'push');
            app.InputOsimxFileSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InputOsimxFileSearchButton.Text = '';
            app.InputOsimxFileEditFieldLabel = uilabel(app.InputsTab);
            app.InputOsimxFileEditFieldLabel.Text = 'Input Osimx File';
            app.InputOsimxFileEditField = uieditfield(app.InputsTab, 'text');
            app.InputOsimxFileWarning = uiimage(app.InputsTab);
            app.InputOsimxFileWarning.Visible = 'off';
            app.InputOsimxFileWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.TrackedQuantitiesSearchButton = uibutton(app.InputsTab, 'push');
            app.TrackedQuantitiesSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.TrackedQuantitiesSearchButton.Text = '';
            app.InputDataEditField = uieditfield(app.InputsTab, 'text');
            app.InputDataError = uiimage(app.InputsTab);
            app.InputDataError.Visible = 'off';
            app.InputDataError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.TrackedQuantitiesDirectoryLabel = uilabel(app.InputsTab);
            app.TrackedQuantitiesDirectoryLabel.Text = {'Tracked Quantities'; 'Directory'};
            app.StatesCoordinatesListTextArea = uitextarea(app.InputsTab);
            app.StatesCoordinatesListTextArea.Editable = 'off';
            app.StatesCoordinatesListTextAreaLabel = uilabel(app.InputsTab);
            app.StatesCoordinatesListTextAreaLabel.Text = 'States Coordinates List';
            app.CoordinateListWarning = uiimage(app.InputsTab);
            app.CoordinateListWarning.Visible = 'off';
            app.CoordinateListWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.CoordinateListEditButton = uibutton(app.InputsTab, 'push');
            app.CoordinateListEditButton.Text = 'Edit';
            app.TrialPrefixEditFieldLabel = uilabel(app.InputsTab);
            app.TrialPrefixEditFieldLabel.Text = 'Trial Prefix';
            app.TrialPrefixEditField = uieditfield(app.InputsTab, 'text');
            app.TaskPrefixesWarning = uiimage(app.InputsTab);
            app.TaskPrefixesWarning.Visible = 'off';
            app.TaskPrefixesWarning.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.TaskPrefixesError = uiimage(app.InputsTab);
            app.TaskPrefixesError.Visible = 'off';
            app.TaskPrefixesError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.MTPResultsDirError = uiimage(app.InputsTab);
            app.MTPResultsDirError.Visible = 'off';
            app.MTPResultsDirError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'error.png');
            app.InitialGuessSearchButton = uibutton(app.InputsTab, 'push');
            app.InitialGuessSearchButton.Icon = fullfile(pathToMLAPP, '..', 'Images', 'folderIcon.svg');
            app.InitialGuessSearchButton.Text = '';
            app.MTPResultsDirEditField = uieditfield(app.InputsTab, 'text');
            app.MTPResultsDirectoryLabel = uilabel(app.InputsTab);
            app.MTPResultsDirectoryLabel.Text = 'Initial Guess Directory';
            app.ToolSelectionDropDownLabel = uilabel(app.InputsTab);
            app.ToolSelectionDropDownLabel.Text = 'Tool Selection';
            app.ToolSelectionDropDown = uidropdown(app.InputsTab);
            app.ToolSelectionDropDown.Items = {'Tracking Optimization', 'Verification Optimization', 'Design Optimization'};
            app.ToolSelectionDropDown.Value = 'Tracking Optimization';
            app.SolverSettingsTab = uitab(app.TabGroup);
            app.ControllersTab = uitab(app.TabGroup);
            app.ControllersTabGroup = uitabgroup(app.ControllersTab);
            app.TorqueTab = uitab(app.ControllersTabGroup);
            app.TorqueTab.Title = 'Tab';
            app.UseRCNLTorqueControllerCheckBox = uicheckbox(app.TorqueTab);
            app.UseRCNLTorqueControllerCheckBox.Text = 'Use RCNL Torque Controller';
            app.TorqueControllerCoordinateEditButton = uibutton(app.TorqueTab, 'push');
            app.TorqueControllerCoordinateEditButton.Text = 'Edit';
            app.TorqueControllerCoordianteListError = uiimage(app.TorqueTab);
            app.TorqueControllerCoordianteListError.Visible = 'off';
            app.TorqueControllerCoordianteListError.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.TorqueControllerCoordinateList = uitextarea(app.TorqueTab);
            app.TorqueControllerCoordinateList.Editable = 'off';
            app.CoordinatesListTextAreaLabel = uilabel(app.TorqueTab);
            app.CoordinatesListTextAreaLabel.Text = 'Coordinates List';
            app.AuxAdvancedSettingsTable = uitable(app.TorqueTab);
            app.AuxAdvancedSettingsTable.ColumnName = {'Parameter'; 'Value'};
            app.AuxAdvancedSettingsTable.RowName = {};
            app.AuxAdvancedSettingsTable.SelectionType = 'row';
            app.AuxAdvancedSettingsTable.ColumnEditable = [false true];
            app.TorqueControllerAdvancedSettings = uilabel(app.TorqueTab);
            app.TorqueControllerAdvancedSettings.Text = {'Advanced'; 'Settings'};
            app.SynergyTab = uitab(app.ControllersTabGroup);
            app.SynergyTab.Title = 'Tab2';
            app.UseRCNLSynergyControllerCheckBox = uicheckbox(app.SynergyTab);
            app.UseRCNLSynergyControllerCheckBox.Text = 'Use RCNL Synergy Controller';
            app.TorqueControllerCoordinateEditButton_2 = uibutton(app.SynergyTab, 'push');
            app.TorqueControllerCoordinateEditButton_2.Text = 'Edit';
            app.TorqueControllerCoordianteListError_2 = uiimage(app.SynergyTab);
            app.TorqueControllerCoordianteListError_2.Visible = 'off';
            app.TorqueControllerCoordianteListError_2.ImageSource = fullfile(pathToMLAPP, '..', 'Images', 'warning.png');
            app.SurrogateModelCoordinatesListLabel = uilabel(app.SynergyTab);
            app.SurrogateModelCoordinatesListLabel.Text = {'Surrogate Model'; 'Coordinates List'};
            app.TorqueControllerCoordinateList_2 = uitextarea(app.SynergyTab);
            app.TorqueControllerCoordinateList_2.Editable = 'off';
            app.UseRCNLMuscleControllerCheckBox = uicheckbox(app.SynergyTab);
            app.UseRCNLMuscleControllerCheckBox.Text = 'Use RCNL Muscle Controller';
            app.SynergyVectorNormalizationMethodLabel = uilabel(app.SynergyTab);
            app.SynergyVectorNormalizationMethodLabel.Text = {'Synergy Vector '; 'Normalization Method '};
            app.SynergyVectorNormalizationMethodDropDown = uidropdown(app.SynergyTab);
            app.SynergyVectorNormalizationMethodDropDown.Items = {'Sum', 'Magnitude'};
            app.SynergyVectorNormalizationMethodDropDown.Value = 'Magnitude';
            app.OptimizeSynergyVectorsCheckBox = uicheckbox(app.SynergyTab);
            app.OptimizeSynergyVectorsCheckBox.Text = 'Optimize Synergy Vectors';
            app.AuxAdvancedSettingsTable_2 = uitable(app.SynergyTab);
            app.AuxAdvancedSettingsTable_2.ColumnName = {'Parameter'; 'Value'};
            app.AuxAdvancedSettingsTable_2.RowName = {};
            app.AuxAdvancedSettingsTable_2.SelectionType = 'row';
            app.AuxAdvancedSettingsTable_2.ColumnEditable = [false true];
            app.AdvancedSettingsLabel = uilabel(app.SynergyTab);
            app.AdvancedSettingsLabel.Text = 'Advanced Settings';
            app.TorqueControllerCoordinateEditButton_3 = uibutton(app.SynergyTab, 'push');
            app.TorqueControllerCoordinateEditButton_3.Text = 'Edit';
            app.SurrogateModelCoordinatesListLabel_2 = uilabel(app.SynergyTab);
            app.SurrogateModelCoordinatesListLabel_2.Text = 'Muscle List';
            app.TorqueControllerCoordinateList_3 = uitextarea(app.SynergyTab);
            app.TorqueControllerCoordinateList_3.Editable = 'off';
            app.AuxAdvancedSettingsTable_3 = uitable(app.SynergyTab);
            app.AuxAdvancedSettingsTable_3.ColumnName = {'Parameter'; 'Value'};
            app.AuxAdvancedSettingsTable_3.RowName = {};
            app.AuxAdvancedSettingsTable_3.SelectionType = 'row';
            app.AuxAdvancedSettingsTable_3.ColumnEditable = [false true];
            app.AdvancedSettingsLabel_2 = uilabel(app.SynergyTab);
            app.AdvancedSettingsLabel_2.Text = 'Advanced Settings';
            app.MuscleTab = uitab(app.ControllersTabGroup);
            app.MuscleTab.Title = 'Tab3';
            app.UserDefinedTab = uitab(app.ControllersTabGroup);
            app.UserDefinedTab.Title = 'Tab4';
            app.Mask1_2 = uiimage(app.ControllersTab);
            app.Mask1_2.ImageSource = fullfile(pathToMLAPP, '..', 'images', 'greyMask.png');
            app.UserDefinedControlsButton = uibutton(app.ControllersTab, 'push');
            app.UserDefinedControlsButton.Text = 'User-defined';
            app.SynergyControlsButton = uibutton(app.ControllersTab, 'push');
            app.SynergyControlsButton.Text = 'Synergy/Muscle';
            app.TorqueControlsButton = uibutton(app.ControllersTab, 'push');
            app.TorqueControlsButton.Text = 'Torque';
            app.CostTermsTab = uitab(app.TabGroup);
            app.ConstraintTermsTab = uitab(app.TabGroup);
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Tab';
            app.Mask1 = uiimage(app.UIFigure);
            app.Mask1.ImageSource = fullfile(pathToMLAPP, '..', 'images', 'greyMask.png');
            app.MTPImage = uiimage(app.UIFigure);
            app.AdvancedButton = uibutton(app.UIFigure, 'push');
            app.AdvancedButton.Text = 'Advanced';
            app.ConstraintTermsButton = uibutton(app.UIFigure, 'push');
            app.ConstraintTermsButton.Text = 'Constraint Terms';
            app.CostTermsButton = uibutton(app.UIFigure, 'push');
            app.CostTermsButton.Text = 'Cost Terms';
            app.ControllersButton = uibutton(app.UIFigure, 'push');
            app.ControllersButton.Text = 'Controllers';
            app.SolverSettingsButton = uibutton(app.UIFigure, 'push');
            app.SolverSettingsButton.Text = 'Solver Settings';
            app.InputsButton = uibutton(app.UIFigure, 'push');
            app.InputsButton.Text = 'Inputs';
            app.RcnlLogo = uiimage(app.UIFigure);
            app.LoadSettingsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsFileButtonPushed, true);
            app.LoadSettingsFileButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.Text = 'Rename';
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.Text = 'Copy';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TreatmentOptimizationBase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end